classdef BPSK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinkDistanceLabel             matlab.ui.control.Label
        LinkDistanceEditField         matlab.ui.control.NumericEditField
        FrequencyLabel                matlab.ui.control.Label
        FrequencyDropDown             matlab.ui.control.DropDown
        ofbiterrorsLabel              matlab.ui.control.Label
        ofbiterrorsEditField          matlab.ui.control.NumericEditField
        HPABackoffLabel               matlab.ui.control.Label
        HPABackoffEditField           matlab.ui.control.NumericEditField
        AtmosphericConditionDropDownLabel  matlab.ui.control.Label
        AtmosphericConditionDropDown  matlab.ui.control.DropDown
        AntennaEfficiencyLabel        matlab.ui.control.Label
        AntennaEfficiencySlider       matlab.ui.control.Slider
        TxAntDiaEditFieldLabel        matlab.ui.control.Label
        TxAntDiaEditField             matlab.ui.control.EditField
        RxAntDiaEditFieldLabel        matlab.ui.control.Label
        RxAntDiaEditField             matlab.ui.control.EditField
        NoiseTemperatureSliderLabel   matlab.ui.control.Label
        NoiseTemperatureSlider        matlab.ui.control.Slider
        RunCommandsPanel              matlab.ui.container.Panel
        SimulateButton                matlab.ui.control.Button
        ViewMatlabCodeButton          matlab.ui.control.Button
        VisualizationsPanel           matlab.ui.container.Panel
        TxRxSpectraCheckBox           matlab.ui.control.CheckBox
        RxConstellationsCheckBox      matlab.ui.control.CheckBox
        HPAConstellationsCheckBox     matlab.ui.control.CheckBox
        ResultsPanel                  matlab.ui.container.Panel
        BitErrorRateEditFieldLabel    matlab.ui.control.Label
        BitErrorRateEditField         matlab.ui.control.NumericEditField
        NumberofErrorsEditFieldLabel  matlab.ui.control.Label
        NumberofErrorsEditField       matlab.ui.control.EditField
        NumberofBitsEditFieldLabel    matlab.ui.control.Label
        NumberofBitsEditField         matlab.ui.control.NumericEditField
        PathLossdBEditFieldLabel      matlab.ui.control.Label
        PathLossdBEditField           matlab.ui.control.NumericEditField
        EbNodBEditFieldLabel          matlab.ui.control.Label
        EbNodBEditField               matlab.ui.control.NumericEditField
        BitRateEditFieldLabel         matlab.ui.control.Label
        BitRateEditField              matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            app.ofbiterrorsEditField.Value = inf;
            app.LinkDistanceEditField.Value = 35600;
            %app.FrequencyDropDown.Value = 4;
            app.HPABackoffEditField.Value = 0;
            app.AntennaEfficiencySlider.Value = 0.5;
            app.TxAntDiaEditField.Value = '0';
            app.RxAntDiaEditField.Value = '0';
            app.NoiseTemperatureSlider.Value = 290;
            
            % Visualization options
            app.HPAConstellationsCheckBox.Value = true;
            app.TxRxSpectraCheckBox.Value = true;
            app.RxConstellationsCheckBox.Value = true;
            
        end

        % Value changed function: LinkDistanceEditField
        function LinkDistanceEditFieldValueChanged(app, event)
      
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ofbiterrorsEditField
        function ofbiterrorsEditFieldValueChanged(app, event)
                      
        end

        % Value changed function: HPABackoffEditField
        function HPABackoffEditFieldValueChanged(app, event)
                 
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            
        end

        % Value changed function: TxRxSpectraCheckBox
        function TxRxSpectraCheckBoxValueChanged(app, event)
                      
        end

        % Value changed function: RxConstellationsCheckBox
        function RxConstellationsCheckBoxValueChanged(app, event)
                      
        end

        % Value changed function: HPAConstellationsCheckBox
        function HPAConstellationsCheckBoxValueChanged(app, event)
                       
        end

        % Value changed function: BitErrorRateEditField
        function BitErrorRateEditFieldValueChanged(app, event)
                   
        end

        % Value changed function: NumberofBitsEditField
        function NumberofBitsEditFieldValueChanged(app, event)
                      
        end

        % Value changed function: PathLossdBEditField
        function PathLossdBEditFieldValueChanged(app, event)
                    
        end

        % Value changed function: EbNodBEditField
        function EbNodBEditFieldValueChanged(app, event)
                       
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            %guidata(app.figure1,app)
            %drawnow
            valueOfbiterrors = app.ofbiterrorsEditField.Value;
            valueLinkDistance = app.LinkDistanceEditField.Value;
            valueFrequency = app.FrequencyDropDown.Value;
            valueHPABackoff = app.HPABackoffEditField.Value;
            valueHPAConstellations = app.HPAConstellationsCheckBox.Value;
            valueRxConstellations = app.RxConstellationsCheckBox.Value;
            valueTxRxSpectra = app.TxRxSpectraCheckBox.Value;
            valueAtmosphericCondition = app.AtmosphericConditionDropDown.Value;
            valueAntennaEfficiency = app.AntennaEfficiencySlider.Value;
            valueTxAntDia = app.TxAntDiaEditField.Value;
            valueRxAntDia = app.RxAntDiaEditField.Value;
            valueNoiseTemperature = app.NoiseTemperatureSlider.Value;
            
            clear BPSKwithRFImpairmentsSim
            % Reset results group
            app.BitErrorRateEditField.Value = 0;
            app.NumberofErrorsEditField.Value = '0';
            app.NumberofBitsEditField.Value = 0;
            app.EbNodBEditField.Value = 0;
            app.PathLossdBEditField.Value = 0;
            
            % Initialize Variables
            ebnoSum = 0;
            errStats = zeros(3,1);
            frmCnt = 0;
            resetBER = false;
%             [channel, channelTime] = hvacDuctChannel(valueFrequency,valueLinkDistance);
            channel = 1/sqrt(2)*(randn(1,1)+1i*randn(1,1));
            channelTime = 0;
            while errStats(2) <= valueOfbiterrors && errStats(3) <= 1e7
                % Call main simulation function
                [errStats,pathLoss,ebnoEst,bernumber,berratio,Capacity] = BPSKwithRFImpairmentsSim(valueLinkDistance,...
                    valueFrequency,valueAtmosphericCondition,valueAntennaEfficiency,...
                    valueTxAntDia, valueRxAntDia, valueHPABackoff, valueNoiseTemperature,...
                    valueRxConstellations,valueTxRxSpectra, valueHPAConstellations, resetBER,channel,channelTime);
                if ~isvalid(app) 
                    % main figure closed during sim; close scopes:
                    rfPlotConstellation([], false, false);
                    rfPlotHPA([], [], false, false);
                    rfPlotSpectrum([], [], false, false);
                    return;
                end
                % Increment frame counter and update Eb/No estimate
                frmCnt = frmCnt + 1;
                ebnoSum = ebnoSum + ebnoEst;
                % Return resetBER flag back to false
                resetBER = false;
                % Update output variables and check for simulation stop
                if rem(frmCnt,10) == 0
                    % Check to see if any values have been updated. If yes, the
                    % resetBER flag is set to true
                    resetBER = any([valueLinkDistance ~= app.LinkDistanceEditField.Value ...
                        valueFrequency ~= app.FrequencyDropDown.Value ...
                        valueHPABackoff ~= app.HPABackoffEditField.Value ...
                        valueOfbiterrors ~= app.BitErrorRateEditField.Value ...
                        valueTxAntDia ~= app.TxAntDiaEditField.Value...
                        valueRxAntDia ~= app.RxAntDiaEditField.Value ...
                        ~strcmp(valueAtmosphericCondition, app.AtmosphericConditionDropDown.Value) ...
                        valueAntennaEfficiency ~= app.AntennaEfficiencySlider.Value...
                        valueNoiseTemperature ~= app.NoiseTemperatureSlider.Value]);
                                               
                    % Update inputs fields from the GUI
                    valueOfbiterrors = app.BitErrorRateEditField.Value;
                    valueLinkDistance = app.LinkDistanceEditField.Value;
                    valueFrequency = app.FrequencyDropDown.Value;
                    valueAntennaEfficiency = app.AntennaEfficiencySlider.Value;
                    valueTxAntDia = app.TxAntDiaEditField.Value;
                    valueRxAntDia = app.RxAntDiaEditField.Value;
                    valueAtmosphericCondition = app.AtmosphericConditionDropDown.Value;
                    valueNoiseTemperature = app.NoiseTemperatureSlider.Value;
                    valueHPABackoff = app.HPABackoffEditField.Value;
                    valueTxRxSpectra = app.TxRxSpectraCheckBox.Value;
                    valueRxConstellations = app.RxConstellationsCheckBox.Value;
                    valueHPAConstellations = app.HPAConstellationsCheckBox.Value;
                    % Update results group
                    app.BitErrorRateEditField.Value = str2double(num2str(errStats(1),'%4.2e'));
                    app.NumberofErrorsEditField.Value = num2str(errStats(2));
                    app.NumberofBitsEditField.Value = str2double(num2str(errStats(3),'%4.2e'));
                    if isnan(ebnoSum/frmCnt)
                        app.EbNodBEditField.Value = 0;
                    else
                        app.EbNodBEditField.Value = str2double(num2str((ebnoSum/frmCnt),'%4.1f'));
                    end
                    %app.EbNodBEditField.Value = str2double(num2str((ebnoSum/frmCnt),'%4.1f'));
                    app.PathLossdBEditField.Value = str2double(num2str(pathLoss,'%4.1f'));
                % Reset the Eb/No estimation if the values have been changed
%                     if resetBER
%                         ebnoSum = 0;
%                         frmCnt = 0;
%                     end
                end
            end
            %Find mean Eb/No value
            ebno = ebnoSum/frmCnt;
            % Update results group
            ber = berratio;
            errors = bernumber;
            app.BitErrorRateEditField.Value = str2double(num2str(errStats(1),'%4.2e'));
            app.NumberofErrorsEditField.Value = num2str(errStats(2));
            app.NumberofBitsEditField.Value = str2double(num2str(errStats(3),'%4.2e'));
            app.BitRateEditField.Value = str2double(num2str(Capacity));
            if isnan(ebno)
                app.EbNodBEditField.Value = 0;
            else
                app.EbNodBEditField.Value = str2double(num2str(ebno,'%4.1f'));
            end
            app.PathLossdBEditField.Value = str2double(num2str(pathLoss,'%4.1f'));
            % Restore GUI appearance
            app.SimulateButton.HandleVisibility = "on"; 
        end

        % Button pushed function: ViewMatlabCodeButton
        function ViewMatlabCodeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LinkDistanceLabel = uilabel(app.UIFigure);
            app.LinkDistanceLabel.Text = 'Link Distance:            ';
            app.LinkDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @LinkDistanceEditFieldValueChanged, true);
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:                 ';
            app.FrequencyDropDown = uidropdown(app.UIFigure);
            app.FrequencyDropDown.Items = {'1', '2', '26', '60'};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = '1';
            app.ofbiterrorsLabel = uilabel(app.UIFigure);
            app.ofbiterrorsLabel.Text = '# of bit errors:            ';
            app.ofbiterrorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofbiterrorsEditField.ValueChangedFcn = createCallbackFcn(app, @ofbiterrorsEditFieldValueChanged, true);
            app.ofbiterrorsEditField.Value = Inf;
            app.HPABackoffLabel = uilabel(app.UIFigure);
            app.HPABackoffLabel.Text = 'HPA Backoff:              ';
            app.HPABackoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.HPABackoffEditField.ValueChangedFcn = createCallbackFcn(app, @HPABackoffEditFieldValueChanged, true);
            app.AtmosphericConditionDropDownLabel = uilabel(app.UIFigure);
            app.AtmosphericConditionDropDownLabel.Text = 'Atmospheric Condition:';
            app.AtmosphericConditionDropDown = uidropdown(app.UIFigure);
            app.AtmosphericConditionDropDown.Items = {'Free Space', 'Fog', 'Gas', 'Rain'};
            app.AtmosphericConditionDropDown.Value = 'Free Space';
            app.AntennaEfficiencyLabel = uilabel(app.UIFigure);
            app.AntennaEfficiencyLabel.Text = 'Antenna Efficiency    :';
            app.AntennaEfficiencySlider = uislider(app.UIFigure);
            app.AntennaEfficiencySlider.Limits = [0 1];
            app.TxAntDiaEditFieldLabel = uilabel(app.UIFigure);
            app.TxAntDiaEditFieldLabel.Text = 'Tx Ant Dia:                 ';
            app.TxAntDiaEditField = uieditfield(app.UIFigure, 'text');
            app.RxAntDiaEditFieldLabel = uilabel(app.UIFigure);
            app.RxAntDiaEditFieldLabel.Text = 'Rx Ant Dia:                ';
            app.RxAntDiaEditField = uieditfield(app.UIFigure, 'text');
            app.NoiseTemperatureSliderLabel = uilabel(app.UIFigure);
            app.NoiseTemperatureSliderLabel.Text = 'Noise Temperature:  ';
            app.NoiseTemperatureSlider = uislider(app.UIFigure);
            app.NoiseTemperatureSlider.Limits = [0 290];
            app.RunCommandsPanel = uipanel(app.UIFigure);
            app.RunCommandsPanel.Title = 'Run Commands';
            app.SimulateButton = uibutton(app.RunCommandsPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ViewMatlabCodeButton = uibutton(app.RunCommandsPanel, 'push');
            app.ViewMatlabCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewMatlabCodeButtonPushed, true);
            app.ViewMatlabCodeButton.Text = 'View Matlab Code';
            app.VisualizationsPanel = uipanel(app.UIFigure);
            app.VisualizationsPanel.Title = 'Visualizations';
            app.TxRxSpectraCheckBox = uicheckbox(app.VisualizationsPanel);
            app.TxRxSpectraCheckBox.ValueChangedFcn = createCallbackFcn(app, @TxRxSpectraCheckBoxValueChanged, true);
            app.TxRxSpectraCheckBox.Text = 'Tx & Rx Spectra';
            app.TxRxSpectraCheckBox.Value = true;
            app.RxConstellationsCheckBox = uicheckbox(app.VisualizationsPanel);
            app.RxConstellationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RxConstellationsCheckBoxValueChanged, true);
            app.RxConstellationsCheckBox.Text = 'Rx Constellations';
            app.RxConstellationsCheckBox.Value = true;
            app.HPAConstellationsCheckBox = uicheckbox(app.VisualizationsPanel);
            app.HPAConstellationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HPAConstellationsCheckBoxValueChanged, true);
            app.HPAConstellationsCheckBox.Text = 'HPA Constellations';
            app.HPAConstellationsCheckBox.Value = true;
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.BitErrorRateEditFieldLabel = uilabel(app.ResultsPanel);
            app.BitErrorRateEditFieldLabel.Text = 'Bit Error Rate';
            app.BitErrorRateEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.BitErrorRateEditField.ValueChangedFcn = createCallbackFcn(app, @BitErrorRateEditFieldValueChanged, true);
            app.NumberofErrorsEditFieldLabel = uilabel(app.ResultsPanel);
            app.NumberofErrorsEditFieldLabel.Text = 'Number of Errors';
            app.NumberofErrorsEditField = uieditfield(app.ResultsPanel, 'text');
            app.NumberofErrorsEditField.Value = '0';
            app.NumberofBitsEditFieldLabel = uilabel(app.ResultsPanel);
            app.NumberofBitsEditFieldLabel.Text = 'Number of Bits';
            app.NumberofBitsEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.NumberofBitsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBitsEditFieldValueChanged, true);
            app.PathLossdBEditFieldLabel = uilabel(app.ResultsPanel);
            app.PathLossdBEditFieldLabel.Text = 'Path Loss(dB)';
            app.PathLossdBEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.PathLossdBEditField.ValueChangedFcn = createCallbackFcn(app, @PathLossdBEditFieldValueChanged, true);
            app.EbNodBEditFieldLabel = uilabel(app.ResultsPanel);
            app.EbNodBEditFieldLabel.Text = 'Eb/No (dB)';
            app.EbNodBEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.EbNodBEditField.ValueChangedFcn = createCallbackFcn(app, @EbNodBEditFieldValueChanged, true);
            app.BitRateEditFieldLabel = uilabel(app.ResultsPanel);
            app.BitRateEditFieldLabel.Text = 'Bit Rate';
            app.BitRateEditField = uieditfield(app.ResultsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BPSK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end